classdef reactordisplay2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TextArea                  matlab.ui.control.TextArea
        RegulatingEditField       matlab.ui.control.NumericEditField
        RegulatingEditFieldLabel  matlab.ui.control.Label
        ShimEditField             matlab.ui.control.NumericEditField
        ShimEditFieldLabel        matlab.ui.control.Label
        SafetyEditField           matlab.ui.control.NumericEditField
        SafetyEditFieldLabel      matlab.ui.control.Label
        TransientEditField        matlab.ui.control.NumericEditField
        TransientEditFieldLabel   matlab.ui.control.Label
        SCRAMButton               matlab.ui.control.StateButton
        ConsolePowerButton        matlab.ui.control.StateButton
        RegulatingUpButton        matlab.ui.control.Button
        ShimUpButton              matlab.ui.control.Button
        RegulatingDownButton      matlab.ui.control.Button
        ShimDownButton            matlab.ui.control.Button
        SafetyDownButton          matlab.ui.control.Button
        SafetyUpButton            matlab.ui.control.Button
        ReactorPowerKnobLabel     matlab.ui.control.Label
        TransientDownButton       matlab.ui.control.Button
        TransientUpButton         matlab.ui.control.Button
        ReactorPowerKnob          matlab.ui.control.DiscreteKnob
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TransientUpButton
        function TransientUpButtonPushed(app, event)
            if app.TransientEditField.Value<100
            app.TransientEditField.Value=app.TransientEditField.Value+1;
            end
        end

        % Button pushed function: SafetyUpButton
        function SafetyUpButtonPushed(app, event)
             if app.SafetyEditField.Value<100
            app.SafetyEditField.Value=app.SafetyEditField.Value+1;
            end
        end

        % Button pushed function: ShimUpButton
        function ShimUpButtonPushed(app, event)
            if app.ShimEditField.Value<100
            app.ShimEditField.Value=app.ShimEditField.Value+1;
            end
        end

        % Button pushed function: RegulatingUpButton
        function RegulatingUpButtonPushed(app, event)
            if app.RegulatingEditField.Value<100
            app.RegulatingEditField.Value=app.RegulatingEditField.Value+1;
            end
        end

        % Button pushed function: TransientDownButton
        function TransientDownButtonPushed(app, event)
             if app.TransientEditField.Value>0
            app.TransientEditField.Value=app.TransientEditField.Value-1;
            end
        end

        % Button pushed function: SafetyDownButton
        function SafetyDownButtonPushed(app, event)
            if app.SafetyEditField.Value>0
            app.SafetyEditField.Value=app.SafetyEditField.Value-1;
            end
        end

        % Button pushed function: ShimDownButton
        function ShimDownButtonPushed(app, event)
            if app.ShimEditField.Value>0
            app.ShimEditField.Value=app.ShimEditField.Value-1;
            end  
        end

        % Button pushed function: RegulatingDownButton
        function RegulatingDownButtonPushed(app, event)
            if app.RegulatingEditField.Value>0
            app.RegulatingEditField.Value=app.RegulatingEditField.Value-1;
            end
        end

        % Value changed function: SCRAMButton
        function SCRAMButtonValueChanged(app, event)
            value1 = app.SCRAMButton.Value;
            app.TransientEditField.Value=100;
            app.SafetyEditField.Value=100;
            app.ShimEditField.Value=100;
            app.RegulatingEditField.Value=100;
            
        end

        % Value changed function: ReactorPowerKnob
        function ReactorPowerKnobValueChanged(app, event)
            value = app.ReactorPowerKnob.Value;
            if value==0.01
                ax=app.UIAxes;
                ax.YLim=[0,.01];
            else
                if value==0.1
                    ax=app.UIAxes;
                    ax.YLim=[0,.1];
                end
            end
            if value==1.0
                    ax=app.UIAxes;
                    ax.YLim=[0,1.0];
            else
                if value==10
                    ax=app.UIAxes;
                    ax.YLim=[0,10];
                end
            end
            if value==100
                    ax=app.UIAxes;
                    ax.YLim=[0,100];
            else
                if value==1000
                    ax=app.UIAxes;
                    ax.YLim=[0,1000];
                end
            end
            if value==10000
                    ax=app.UIAxes;
                    ax.YLim=[0,10000];
            else
                if value==100000
                    ax=app.UIAxes;
                    ax.YLim=[0,100000];
                end
            end
            if value==1000000
                    ax=app.UIAxes;
                    ax.YLim=[0,1000000];
            end
                
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: ConsolePowerButton
        function ConsolePowerButtonValueChanged(app, event)
            run=true;
            tim=1;
            check=0;
            actual_time=[];
            tot_p=[];
            temp=[];
            fueltemp=[];
            PRKEtime_values = linspace(0, 0.01, 10000);
            generationTime = 1 * (10^-7); % Mean neutron generation time
           decay_constants = [0.00124, 0.031, 0.111, 0.301, 1.136, 3.014]; % Decay constants for each precursor group
            beta_i = [0.00021, 0.00141, 0.00127, 0.00255, 0.00074, 0.00027]; % Beta values for precursor groups
            beta = sum(beta_i); % Effective beta;
            
            TB=34;

            Toutold=27;
            reactivity=0;
            initial_iodine_pop=0;
            initial_xenon_pop=0;
            
           
            value = app.ConsolePowerButton.Value;
           
            
            initial_power=value;
            initial_concentration = beta_i./(decay_constants.*generationTime).*initial_power;
            init1=initial_concentration(1);
            init2=initial_concentration(2);
            init3=initial_concentration(3);
            init4=initial_concentration(4);
            init5=initial_concentration(5);
            init6=initial_concentration(6);
            realtime=1;


            while run ; true;
            app.TextArea.Value={'Reactor is operating'};
            xval=realtime/100;
            u=app.TransientEditField.Value;
            wierd=app.SafetyEditField.Value;
            o=app.ShimEditField.Value;
            p=app.RegulatingEditField.Value;

            [initial_power,reactivity,init1,init2,init3,init4,init5,init6,Toutold,TB,initial_iodine_pop,initial_xenon_pop,Tf] = masterloop(initial_power,reactivity,init1,init2,init3,init4,init5,init6,Toutold,TB,initial_iodine_pop,initial_xenon_pop,u,wierd,o,p);
            arr = [initial_power,reactivity,init1,init2,init3,init4,init5,init6,Toutold,TB,initial_iodine_pop,initial_xenon_pop,Tf];
            initial_power=arr(1);
            if initial_power>50
               
            run= false;
            app.TextArea.Value={'Reactor went Supercritical and system diverged! Press console power twice to reset. '};
            tot_p=[];
            temp=[];
            fueltemp=[];
            app.TransientEditField.Value=98;
            app.SafetyEditField.Value=91;
            app.ShimEditField.Value=94;
            app.RegulatingEditField.Value=89;
            initial_power=value;
            end 
            reactivity=arr(2);
            init1=arr(3);
            init2=arr(4);
            init3=arr(5);
            init4=arr(6);
            init5=arr(7);
            init6=arr(8);
            Toutold=arr(9);
            TB=arr(10);
            initial_iodine_pop=arr(11);
            initial_xenon_pop=arr(12);
            Tf=arr(13);
        
            actual_time(tim) = xval;    
            tot_p(tim)=initial_power;
            temp(tim)=TB;
            fueltemp(tim)=Tf;
            tim = tim+1;
            realtime=realtime+1;
            
            check=tim;
            plot(app.UIAxes,actual_time,tot_p);
            drawnow
            
            
            plot(app.UIAxes2,actual_time,temp);
            drawnow
            plot(app.UIAxes3,actual_time,fueltemp);
            drawnow
            if check>1000
            check=0;
            tim=1;
          
            actual_time=[];
            tot_p=[];
            temp=[];
            fueltemp=[];
          
            end 
           
            end 
            
   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Reactor Power')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Reactor power (MW)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Water Temperature')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Temperature (C)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Fuel Temperature')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Temperature (C)')
            zlabel(app.UIAxes3, 'Z')
            app.ReactorPowerKnob = uiknob(app.UIFigure, 'discrete');
            app.ReactorPowerKnob.Items = {'10mw', '100mw', '1W', '10W', '100W', '1KW', '10Kw', '100Kw', '1Mw'};
            app.ReactorPowerKnob.ItemsData = {'0.00000001', '0.0000001', '0.000001', '0.00001', '0.0001', '0.001', '0.01', '0.1', '1'};
            app.ReactorPowerKnob.ValueChangedFcn = createCallbackFcn(app, @ReactorPowerKnobValueChanged, true);
            app.ReactorPowerKnob.Value = '0.00000001';
            app.TransientUpButton = uibutton(app.UIFigure, 'push');
            app.TransientUpButton.ButtonPushedFcn = createCallbackFcn(app, @TransientUpButtonPushed, true);
            app.TransientUpButton.Text = 'Transient Up';
            app.TransientDownButton = uibutton(app.UIFigure, 'push');
            app.TransientDownButton.ButtonPushedFcn = createCallbackFcn(app, @TransientDownButtonPushed, true);
            app.TransientDownButton.Text = 'Transient Down';
            app.ReactorPowerKnobLabel = uilabel(app.UIFigure);
            app.ReactorPowerKnobLabel.Text = 'Reactor Power';
            app.SafetyUpButton = uibutton(app.UIFigure, 'push');
            app.SafetyUpButton.ButtonPushedFcn = createCallbackFcn(app, @SafetyUpButtonPushed, true);
            app.SafetyUpButton.Text = 'Safety Up';
            app.SafetyDownButton = uibutton(app.UIFigure, 'push');
            app.SafetyDownButton.ButtonPushedFcn = createCallbackFcn(app, @SafetyDownButtonPushed, true);
            app.SafetyDownButton.Text = 'Safety Down';
            app.ShimDownButton = uibutton(app.UIFigure, 'push');
            app.ShimDownButton.ButtonPushedFcn = createCallbackFcn(app, @ShimDownButtonPushed, true);
            app.ShimDownButton.Text = ' Shim Down';
            app.RegulatingDownButton = uibutton(app.UIFigure, 'push');
            app.RegulatingDownButton.ButtonPushedFcn = createCallbackFcn(app, @RegulatingDownButtonPushed, true);
            app.RegulatingDownButton.Text = 'Regulating Down';
            app.ShimUpButton = uibutton(app.UIFigure, 'push');
            app.ShimUpButton.ButtonPushedFcn = createCallbackFcn(app, @ShimUpButtonPushed, true);
            app.ShimUpButton.Text = 'Shim Up';
            app.RegulatingUpButton = uibutton(app.UIFigure, 'push');
            app.RegulatingUpButton.ButtonPushedFcn = createCallbackFcn(app, @RegulatingUpButtonPushed, true);
            app.RegulatingUpButton.Text = 'Regulating Up';
            app.ConsolePowerButton = uibutton(app.UIFigure, 'state');
            app.ConsolePowerButton.ValueChangedFcn = createCallbackFcn(app, @ConsolePowerButtonValueChanged, true);
            app.ConsolePowerButton.Text = 'Console Power';
            app.SCRAMButton = uibutton(app.UIFigure, 'state');
            app.SCRAMButton.ValueChangedFcn = createCallbackFcn(app, @SCRAMButtonValueChanged, true);
            app.SCRAMButton.Text = 'SCRAM';
            app.TransientEditFieldLabel = uilabel(app.UIFigure);
            app.TransientEditFieldLabel.Text = 'Transient';
            app.TransientEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransientEditField.Value = 98;
            app.SafetyEditFieldLabel = uilabel(app.UIFigure);
            app.SafetyEditFieldLabel.Text = 'Safety';
            app.SafetyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SafetyEditField.Value = 91;
            app.ShimEditFieldLabel = uilabel(app.UIFigure);
            app.ShimEditFieldLabel.Text = 'Shim';
            app.ShimEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShimEditField.Value = 94;
            app.RegulatingEditFieldLabel = uilabel(app.UIFigure);
            app.RegulatingEditFieldLabel.Text = 'Regulating';
            app.RegulatingEditField = uieditfield(app.UIFigure, 'numeric');
            app.RegulatingEditField.Value = 89;
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Reactor is off'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reactordisplay2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end