classdef Control_Panel_Plot_Test_7_Working_Plot_New < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        POWERSAFETYSCRAMButton    matlab.ui.control.StateButton
        PERIODSCRAMButton         matlab.ui.control.StateButton
        PeriodEditField           matlab.ui.control.NumericEditField
        PeriodEditFieldLabel      matlab.ui.control.Label
        ReadyButton               matlab.ui.control.StateButton
        RegulatingScramButton     matlab.ui.control.Button
        ShimScramButton           matlab.ui.control.Button
        SafetyScramButton         matlab.ui.control.Button
        TransientScramButton      matlab.ui.control.Button
        RegulatingDOWNButton      matlab.ui.control.StateButton
        RegulatingUPButton        matlab.ui.control.StateButton
        ShimDOWNButton            matlab.ui.control.StateButton
        ShimUPButton              matlab.ui.control.StateButton
        SafetyDOWNButton          matlab.ui.control.StateButton
        SafetyUPButton            matlab.ui.control.StateButton
        TransientDOWNButton       matlab.ui.control.StateButton
        TransientUPButton         matlab.ui.control.StateButton
        TransientRodFireLabel     matlab.ui.control.Label
        Lamp_9                    matlab.ui.control.Lamp
        SCRAMButton               matlab.ui.control.Button
        POWERONButton             matlab.ui.control.Button
        ReactorPowerKnob          matlab.ui.control.DiscreteKnob
        ReactorPowerKnobLabel     matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        RegulatingEditField       matlab.ui.control.NumericEditField
        RegulatingEditFieldLabel  matlab.ui.control.Label
        ShimEditField             matlab.ui.control.NumericEditField
        ShimEditFieldLabel        matlab.ui.control.Label
        SafetyEditField           matlab.ui.control.NumericEditField
        SafetyEditFieldLabel      matlab.ui.control.Label
        TransientEditField        matlab.ui.control.NumericEditField
        TransientEditFieldLabel   matlab.ui.control.Label
        EditField                 matlab.ui.control.EditField
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        crtimer % timer callback
        UserData
        appstop
        Period % Description
        Power_new
    end
    
    methods (Access = private)
        
        function Timestep(app, obj, event, string_arg)%Functions that change the value of the edit field for each control rod
            if app.TransientEditField.Value<100
            app.TransientEditField.Value=app.TransientEditField.Value+.25;
            end
        end
        
        
        function Timestep2(app, obj, event, string_arg)
            if app.SafetyEditField.Value<100
            app.SafetyEditField.Value=app.SafetyEditField.Value+.25;
            end
        end
        
        function Timestep3(app, obj, event, string_arg)
            if app.ShimEditField.Value<100
            app.ShimEditField.Value=app.ShimEditField.Value+.25;
            end
        end
        
        function Timestep4(app, obj, event, string_arg)
            if app.RegulatingEditField.Value<100
            app.RegulatingEditField.Value=app.RegulatingEditField.Value+.25;
            end
        end
        
        function Timestep5(app, obj, event, string_arg)%Functions that change the value of the edit field for each control rod
            if app.TransientEditField.Value>0
            app.TransientEditField.Value=app.TransientEditField.Value-.25;
            end
            
        end
        
        function Timestep6(app, obj, event, string_arg)
            if app.SafetyEditField.Value>0
            app.SafetyEditField.Value=app.SafetyEditField.Value-.25;
            end
        end

        function Timestep7(app, obj, event, string_arg)
            if app.ShimEditField.Value>0
            app.ShimEditField.Value=app.ShimEditField.Value-.25;
            end    
        end
        
        function Timestep8(app, obj, event, string_arg)
            if app.RegulatingEditField.Value>0
            app.RegulatingEditField.Value=app.RegulatingEditField.Value-.25;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ReactorPowerKnob, UIAxes
        function ReactorPowerKnobValueChanged(app, event)
            value = app.ReactorPowerKnob.Value;
            if value==0.01
                ax=app.UIAxes;
                ax.YLim=[0,.01];
            else
                if value==0.1
                    ax=app.UIAxes;
                    ax.YLim=[0,.1];
                end
            end
            if value==1.0
                    ax=app.UIAxes;
                    ax.YLim=[0,1.0];
            else
                if value==10
                    ax=app.UIAxes;
                    ax.YLim=[0,10];
                end
            end
            if value==100
                    ax=app.UIAxes;
                    ax.YLim=[0,100];
            else
                if value==1000
                    ax=app.UIAxes;
                    ax.YLim=[0,1000];
                end
            end
            if value==10000
                    ax=app.UIAxes;
                    ax.YLim=[0,10000];
            else
                if value==100000
                    ax=app.UIAxes;
                    ax.YLim=[0,100000];
                end
            end
            if value==1000000
                    ax=app.UIAxes;
                    ax.YLim=[0,1000000];
            end
                
        end

        % Value changed function: TransientUPButton
        function TransientUPButtonValueChanged(app, event)
            value = app.TransientUPButton.Value;
            app.crtimer=timer;
            app.crtimer.Period=.1;
            app.crtimer.TasksToExecute= inf;
            app.crtimer.ExecutionMode= 'fixedRate';
            app.crtimer.TimerFcn= {@app.Timestep, 'start timer'};
            app.crtimer.StopFcn={@app.Timestep, 'stop timer'};
            
            if value==1 && app.ReadyButton.Value == 1
                app.TransientUPButton.BackgroundColor=[0.93,0.69,0.13];
                start(app.crtimer)
            else
                app.TransientUPButton.BackgroundColor=[0.96,0.96,0.96];
                stop(timerfind)
                delete(timerfind)
            end
        end

        % Value changed function: SafetyUPButton
        function SafetyUPButtonValueChanged(app, event)
            value = app.SafetyUPButton.Value;
            app.crtimer=timer;
            app.crtimer.Period=.1;
            app.crtimer.TasksToExecute= inf;
            app.crtimer.ExecutionMode= 'fixedRate';
            app.crtimer.TimerFcn= {@app.Timestep2, 'start timer'};
            app.crtimer.StopFcn={@app.Timestep2, 'stop timer'};
   
disp('bp1')
            if value==1
                app.SafetyUPButton.BackgroundColor=[0.93,0.69,0.13];
                start(app.crtimer)
            disp('bp2')
           
                
            else
                app.SafetyUPButton.BackgroundColor=[0.96,0.96,0.96];
                stop(timerfind)
                delete(timerfind)
            end
        end

        % Value changed function: ShimUPButton
        function ShimUPButtonValueChanged(app, event)
            value = app.ShimUPButton.Value;
            app.crtimer=timer;
            app.crtimer.Period=.1;
            app.crtimer.TasksToExecute= inf;
            app.crtimer.ExecutionMode= 'fixedRate';
            app.crtimer.TimerFcn= {@app.Timestep3, 'start timer'};
            app.crtimer.StopFcn={@app.Timestep3, 'stop timer'};
   
            if value==1
                app.ShimUPButton.BackgroundColor=[0.93,0.69,0.13];
                start(app.crtimer) 
            else
                app.ShimUPButton.BackgroundColor=[0.96,0.96,0.96];
                stop(timerfind)
                delete(timerfind)
            end
        end

        % Value changed function: RegulatingUPButton
        function RegulatingUPButtonValueChanged(app, event)
            value = app.RegulatingUPButton.Value;
            app.crtimer=timer;
            app.crtimer.Period=.1;
            app.crtimer.TasksToExecute= inf;
            app.crtimer.ExecutionMode= 'fixedRate';
            app.crtimer.TimerFcn= {@app.Timestep4, 'start timer'};
            app.crtimer.StopFcn={@app.Timestep4, 'stop timer'};
   
            if value==1
                app.RegulatingUPButton.BackgroundColor=[0.93,0.69,0.13];
                start(app.crtimer)
            else
                app.RegulatingUPButton.BackgroundColor=[0.96,0.96,0.96];
                stop(timerfind)
                delete(timerfind)
            end
        end

        % Value changed function: TransientDOWNButton
        function TransientDOWNButtonValueChanged(app, event)
            value = app.TransientDOWNButton.Value;
            app.crtimer=timer;
            app.crtimer.Period=.1;
            app.crtimer.TasksToExecute= inf;
            app.crtimer.ExecutionMode= 'fixedRate';
            app.crtimer.TimerFcn= {@app.Timestep5, 'start timer'};
            app.crtimer.StopFcn={@app.Timestep5, 'stop timer'};
   
            if value==1 && app.ReadyButton.Value == 1
                app.TransientDOWNButton.BackgroundColor=[0.93,0.69,0.13];
                start(app.crtimer)
            else
                app.TransientDOWNButton.BackgroundColor=[0.96,0.96,0.96];
                stop(timerfind)
                delete(timerfind)
            end
        end

        % Value changed function: ShimDOWNButton
        function ShimDOWNButtonValueChanged(app, event)
            value = app.ShimDOWNButton.Value;
            app.crtimer=timer;
            app.crtimer.Period=.1;
            app.crtimer.TasksToExecute= inf;
            app.crtimer.ExecutionMode= 'fixedRate';
            app.crtimer.TimerFcn= {@app.Timestep7, 'start timer'};
            app.crtimer.StopFcn={@app.Timestep7, 'stop timer'};
   
            if value==1
                app.ShimDOWNButton.BackgroundColor=[0.93,0.69,0.13];
                start(app.crtimer)
            else
                app.ShimDOWNButton.BackgroundColor=[0.96,0.96,0.96];
                stop(timerfind)
                delete(timerfind)
            end
        end

        % Value changed function: SafetyDOWNButton
        function SafetyDOWNButtonValueChanged(app, event)
            value = app.SafetyDOWNButton.Value;
            app.crtimer=timer;
            app.crtimer.Period=.1;
            app.crtimer.TasksToExecute= inf;
            app.crtimer.ExecutionMode= 'fixedRate';
            app.crtimer.TimerFcn= {@app.Timestep6, 'start timer'};
            app.crtimer.StopFcn={@app.Timestep6, 'stop timer'};
   
            if value==1
                app.SafetyDOWNButton.BackgroundColor=[0.93,0.69,0.13];
                start(app.crtimer)
            else
                app.SafetyDOWNButton.BackgroundColor=[0.96,0.96,0.96];
                stop(timerfind)
                delete(timerfind)
            end
        end

        % Value changed function: RegulatingDOWNButton
        function RegulatingDOWNButtonValueChanged(app, event)
            value = app.RegulatingDOWNButton.Value;
            app.crtimer=timer;
            app.crtimer.Period=.1;
            app.crtimer.TasksToExecute= inf;
            app.crtimer.ExecutionMode= 'fixedRate';
            app.crtimer.TimerFcn= {@app.Timestep8, 'start timer'};
            app.crtimer.StopFcn={@app.Timestep8, 'stop timer'};
   
            if value==1
                app.RegulatingDOWNButton.BackgroundColor=[0.93,0.69,0.13];
                start(app.crtimer)
            else
                app.RegulatingDOWNButton.BackgroundColor=[0.96,0.96,0.96];
                stop(timerfind)
                delete(timerfind)
            end
        end

        % Button pushed function: SCRAMButton
        function SCRAMButtonPushed(app, event)
        app.TransientEditField.Value=0;
        app.SafetyEditField.Value=0;
        app.ShimEditField.Value=0;
        app.RegulatingEditField.Value=0;
        end

        % Button pushed function: POWERONButton
        function POWER_ON_ButtonPushed(app, event)
        app1.Test = animatedline(app.UIAxes);
        
        app.appstop = false; 
        day2sec = 24*60*60; %conversion to seconds
        startTime = now; %Current Time
        
        Power = zeros(60,1);
        t = zeros(60,1);
        Temperature = ones(60,1)*300;
        %constants and inital values
        L=23*(10^-6);       %One group generation time [s]
        beta=0.0075;        %Delayed neutron fraction
        lambda=0.077;       %Decay constant
        Cp=5*(10^5);        %Reactor heat cap. [J/K]
        alpha=-5*(10^-4);   %Temp coefficient [drho/dK]
        dt = .01;
        while app.appstop == false
            %Initial Conditions
        T1 = 300;           %Initial reactor temp. [K]
        N1 = 0.02;          %Initial reactor power [W]
        C1 = ((beta.*N1)/L)/lambda;
        dollars_Transient = (-(2.7/2)*cos((pi./100)*app.TransientEditField.Value)+(2.7/2)); %Transient interpolation
        dollars_Safety = (-(2.15/2)*cos((pi./100)*app.SafetyEditField.Value)+(2.15/2)); %Safety interpolation
        dollars_Shim = (-(2.6/2)*cos((pi./100)*app.ShimEditField.Value)+(2.6/2)); %Shim interpolation
        dollars_Regulating = (-(3.25/2)*cos((pi./100)*app.RegulatingEditField.Value)+(3.25/2)); %Regulating Interpolation
        dollars_total = dollars_Transient + dollars_Safety + dollars_Shim + dollars_Regulating;  %Total Rod Worth
        rho = dollars_total*beta;  %Reactivity insertion
        t2 = (now - startTime)*day2sec;     %Real-Time Calculation
        t = [t(2:end);t2];
        deltaN=exp(((rho-beta)./(L.*t(end)))).*N1+(lambda.*C1*((exp(((rho-beta)./(L*t(end)))))-1).*((rho-beta)./(L*t(end))).^-1); %Calculating change in power
        N2 = N1 + (deltaN.*dt); %Calculating next projected power value
        N_old = N2;
        deltaN=exp(((rho-beta)./(L.*t(end)))).*N_old+(lambda.*C1*((exp((((rho-beta)./(L.*t(end)))))-1).*((rho-beta)./(L.*t(end))).^-1)); %updating new change in power
        N2 = N_old + (deltaN.*dt);
        Navg = ((N2+N_old)./2); %Calculating the average power value
            
        deltaE = Navg.*t(end); % Calculating energy released
        
        deltaC = ((beta./L).*N1)-(lambda.*C1); %Calculating change in concentration
            C2 = C1 + (deltaC.*t(end)); %Calculating next conentration value
            C_old = C2;
            deltaC = ((beta./L).*N_old)-(lambda.*C_old);
            C2 = C_old + (deltaC.*t(end));
            Cavg = ((C2+C1)./2); %Calculating average concentration value
            
            
            T_old = Temperature(end);
            T2 = T_old+Navg/Cp;
            rho = rho +(alpha.*(T2-T1));
            N1 = Navg; %Storing Power for next period
            C1 = Cavg; %Storing Concentation for next period
            
            
            app.Power_new = N1;
            Power = [Power(2:end);app.Power_new(end)];
            Temp_new = T2;
            Temperature = [Temperature(2:end); Temp_new(end)];
            app.Period = t(end)./(log(N1/N_old)); 
            app.PeriodEditField.Value = app.Period;
           
            app.UIAxes.XLim = [t(1:1) max(t)];
            
            plot(app.UIAxes,t,Power)
            plot(app.UIAxes2,t,Temperature)
            
            drawnow
        end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
               YN = uiconfirm(app.UIFigure,'Do you want to close the Educational Reactor Simulator?', 'Close request'); %Warning message before Closing
if strcmpi(YN,'OK') %If press ok, then closes app
    delete(app) %Closes App
end
            
        end

        % Button pushed function: TransientScramButton
        function TransientScramButtonPushed(app, event)
            app.TransientEditField.Value=0;
        end

        % Button pushed function: SafetyScramButton
        function SafetyScramButtonPushed(app, event)
            app.SafetyEditField.Value=0;
        end

        % Button pushed function: ShimScramButton
        function ShimScramButtonPushed(app, event)
            app.ShimEditField.Value=0;
        end

        % Button pushed function: RegulatingScramButton
        function RegulatingScramButtonPushed(app, event)
            app.RegulatingEditField.Value=0;
        end

        % Value changed function: POWERSAFETYSCRAMButton
        function POWERSAFETYSCRAMButtonValueChanged(app, event)
            value = app.POWERSAFETYSCRAMButton.Value;
            while app.Power_new < 1.06E6
                value=1;
                app.Lamp_10.Color=[1.00,0.00,0.00];
                app.SAFETYPOWERSCRAMButton.BackgroundColor=[1.00,0.00,0.00];
                app.TransientEditField.Value=0;
                app.SafetyEditField.Value=0;
                app.ShimEditField.Value=0;
                app.RegulatingEditField.Value=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Power')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Power [Watts]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 0.001];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @ReactorPowerKnobValueChanged, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Temperature')
            xlabel(app.UIAxes2, 'Time [s]')
            ylabel(app.UIAxes2, 'Temperature [K]')
            zlabel(app.UIAxes2, 'Z')
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.TransientEditFieldLabel = uilabel(app.UIFigure);
            app.TransientEditFieldLabel.Text = 'Transient';
            app.TransientEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransientEditField.Limits = [0 100];
            app.TransientEditField.ValueDisplayFormat = '%.1f';
            app.TransientEditField.Editable = 'off';
            app.SafetyEditFieldLabel = uilabel(app.UIFigure);
            app.SafetyEditFieldLabel.Text = 'Safety';
            app.SafetyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SafetyEditField.Limits = [0 100];
            app.SafetyEditField.ValueDisplayFormat = '%.1f';
            app.SafetyEditField.Editable = 'off';
            app.ShimEditFieldLabel = uilabel(app.UIFigure);
            app.ShimEditFieldLabel.Text = 'Shim';
            app.ShimEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShimEditField.Limits = [0 100];
            app.ShimEditField.ValueDisplayFormat = '%.1f';
            app.ShimEditField.Editable = 'off';
            app.RegulatingEditFieldLabel = uilabel(app.UIFigure);
            app.RegulatingEditFieldLabel.Text = 'Regulating';
            app.RegulatingEditField = uieditfield(app.UIFigure, 'numeric');
            app.RegulatingEditField.Limits = [0 100];
            app.RegulatingEditField.ValueDisplayFormat = '%.1f';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ReactorPowerKnobLabel = uilabel(app.UIFigure);
            app.ReactorPowerKnobLabel.Text = 'Reactor Power';
            app.ReactorPowerKnob = uiknob(app.UIFigure, 'discrete');
            app.ReactorPowerKnob.Items = {'10mW', '100mW', '1W', '10W', '100W', '1KW', '10KW', '100KW', '1MW'};
            app.ReactorPowerKnob.ItemsData = [0.01 0.1 1 10 100 1000 10000 100000 1000000];
            app.ReactorPowerKnob.ValueChangedFcn = createCallbackFcn(app, @ReactorPowerKnobValueChanged, true);
            app.ReactorPowerKnob.Value = 0.01;
            app.POWERONButton = uibutton(app.UIFigure, 'push');
            app.POWERONButton.ButtonPushedFcn = createCallbackFcn(app, @POWER_ON_ButtonPushed, true);
            app.POWERONButton.Text = {'POWER '; 'ON'};
            app.SCRAMButton = uibutton(app.UIFigure, 'push');
            app.SCRAMButton.ButtonPushedFcn = createCallbackFcn(app, @SCRAMButtonPushed, true);
            app.SCRAMButton.Text = 'SCRAM';
            app.Lamp_9 = uilamp(app.UIFigure);
            app.TransientRodFireLabel = uilabel(app.UIFigure);
            app.TransientRodFireLabel.Text = {'Transient Rod'; 'Fire'};
            app.TransientUPButton = uibutton(app.UIFigure, 'state');
            app.TransientUPButton.ValueChangedFcn = createCallbackFcn(app, @TransientUPButtonValueChanged, true);
            app.TransientUPButton.Text = 'Transient UP';
            app.TransientDOWNButton = uibutton(app.UIFigure, 'state');
            app.TransientDOWNButton.ValueChangedFcn = createCallbackFcn(app, @TransientDOWNButtonValueChanged, true);
            app.TransientDOWNButton.Text = 'Transient DOWN';
            app.SafetyUPButton = uibutton(app.UIFigure, 'state');
            app.SafetyUPButton.ValueChangedFcn = createCallbackFcn(app, @SafetyUPButtonValueChanged, true);
            app.SafetyUPButton.Text = 'Safety UP';
            app.SafetyDOWNButton = uibutton(app.UIFigure, 'state');
            app.SafetyDOWNButton.ValueChangedFcn = createCallbackFcn(app, @SafetyDOWNButtonValueChanged, true);
            app.SafetyDOWNButton.Text = 'Safety DOWN';
            app.ShimUPButton = uibutton(app.UIFigure, 'state');
            app.ShimUPButton.ValueChangedFcn = createCallbackFcn(app, @ShimUPButtonValueChanged, true);
            app.ShimUPButton.Text = 'Shim UP';
            app.ShimDOWNButton = uibutton(app.UIFigure, 'state');
            app.ShimDOWNButton.ValueChangedFcn = createCallbackFcn(app, @ShimDOWNButtonValueChanged, true);
            app.ShimDOWNButton.Text = 'Shim DOWN';
            app.RegulatingUPButton = uibutton(app.UIFigure, 'state');
            app.RegulatingUPButton.ValueChangedFcn = createCallbackFcn(app, @RegulatingUPButtonValueChanged, true);
            app.RegulatingUPButton.Text = 'Regulating UP';
            app.RegulatingDOWNButton = uibutton(app.UIFigure, 'state');
            app.RegulatingDOWNButton.ValueChangedFcn = createCallbackFcn(app, @RegulatingDOWNButtonValueChanged, true);
            app.RegulatingDOWNButton.Text = 'Regulating DOWN';
            app.TransientScramButton = uibutton(app.UIFigure, 'push');
            app.TransientScramButton.ButtonPushedFcn = createCallbackFcn(app, @TransientScramButtonPushed, true);
            app.TransientScramButton.Text = 'Transient Scram';
            app.SafetyScramButton = uibutton(app.UIFigure, 'push');
            app.SafetyScramButton.ButtonPushedFcn = createCallbackFcn(app, @SafetyScramButtonPushed, true);
            app.SafetyScramButton.Text = 'Safety Scram';
            app.ShimScramButton = uibutton(app.UIFigure, 'push');
            app.ShimScramButton.ButtonPushedFcn = createCallbackFcn(app, @ShimScramButtonPushed, true);
            app.ShimScramButton.Text = 'Shim Scram';
            app.RegulatingScramButton = uibutton(app.UIFigure, 'push');
            app.RegulatingScramButton.ButtonPushedFcn = createCallbackFcn(app, @RegulatingScramButtonPushed, true);
            app.RegulatingScramButton.Text = 'Regulating Scram';
            app.ReadyButton = uibutton(app.UIFigure, 'state');
            app.ReadyButton.Text = 'Ready';
            app.PeriodEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodEditField.Editable = 'off';
            app.PERIODSCRAMButton = uibutton(app.UIFigure, 'state');
            app.PERIODSCRAMButton.Text = 'PERIOD SCRAM';
            app.POWERSAFETYSCRAMButton = uibutton(app.UIFigure, 'state');
            app.POWERSAFETYSCRAMButton.ValueChangedFcn = createCallbackFcn(app, @POWERSAFETYSCRAMButtonValueChanged, true);
            app.POWERSAFETYSCRAMButton.Text = 'POWER SAFETY SCRAM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Control_Panel_Plot_Test_7_Working_Plot_New

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end