classdef reactordisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RegulatingEditField       matlab.ui.control.NumericEditField
        RegulatingEditFieldLabel  matlab.ui.control.Label
        ShimEditField             matlab.ui.control.NumericEditField
        ShimEditFieldLabel        matlab.ui.control.Label
        SafetyEditField           matlab.ui.control.NumericEditField
        SafetyEditFieldLabel      matlab.ui.control.Label
        TransientEditField        matlab.ui.control.NumericEditField
        TransientEditFieldLabel   matlab.ui.control.Label
        SCRAMButton               matlab.ui.control.StateButton
        ConsolePowerButton        matlab.ui.control.StateButton
        RegulatingUpButton        matlab.ui.control.Button
        ShimUpButton              matlab.ui.control.Button
        RegulatingDownButton      matlab.ui.control.Button
        ShimDownButton            matlab.ui.control.Button
        SafetyDownButton          matlab.ui.control.Button
        SafetyUpButton            matlab.ui.control.Button
        ReactorPowerKnobLabel     matlab.ui.control.Label
        TransientDownButton       matlab.ui.control.Button
        TransientUpButton         matlab.ui.control.Button
        ReactorPowerKnob          matlab.ui.control.DiscreteKnob
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end


    methods (Access = public)
        
        function results = func(app)
            
        end
    end
    
    methods (Access = private)
        
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TransientUpButton
        function TransientUpButtonPushed(app, event)
            if app.TransientEditField.Value<100
            app.TransientEditField.Value=app.TransientEditField.Value+1;
            end
        end

        % Button pushed function: SafetyUpButton
        function SafetyUpButtonPushed(app, event)
             if app.SafetyEditField.Value<100
            app.SafetyEditField.Value=app.SafetyEditField.Value+1;
            end
        end

        % Button pushed function: ShimUpButton
        function ShimUpButtonPushed(app, event)
            if app.ShimEditField.Value<100
            app.ShimEditField.Value=app.ShimEditField.Value+1;
            end
        end

        % Button pushed function: RegulatingUpButton
        function RegulatingUpButtonPushed(app, event)
            if app.RegulatingEditField.Value<100
            app.RegulatingEditField.Value=app.RegulatingEditField.Value+1;
            end
        end

        % Button pushed function: TransientDownButton
        function TransientDownButtonPushed(app, event)
             if app.TransientEditField.Value>0
            app.TransientEditField.Value=app.TransientEditField.Value-1;
            end
        end

        % Button pushed function: SafetyDownButton
        function SafetyDownButtonPushed(app, event)
            if app.SafetyEditField.Value>0
            app.SafetyEditField.Value=app.SafetyEditField.Value-1;
            end
        end

        % Button pushed function: ShimDownButton
        function ShimDownButtonPushed(app, event)
            if app.ShimEditField.Value>0
            app.ShimEditField.Value=app.ShimEditField.Value-1;
            end  
        end

        % Button pushed function: RegulatingDownButton
        function RegulatingDownButtonPushed(app, event)
            if app.RegulatingEditField.Value>0
            app.RegulatingEditField.Value=app.RegulatingEditField.Value-1;
            end
        end

        % Value changed function: SCRAMButton
        function SCRAMButtonValueChanged(app, event)
            value = app.SCRAMButton.Value;
            app.TransientEditField.Value=0;
            app.SafetyEditField.Value=0;
            app.ShimEditField.Value=0;
            app.RegulatingEditField.Value=0;
        end

        % Value changed function: ReactorPowerKnob
        function ReactorPowerKnobValueChanged(app, event)
            value = app.ReactorPowerKnob.Value;
            if value==0.01
                ax=app.UIAxes;
                ax.YLim=[0,.01];
            else
                if value==0.1
                    ax=app.UIAxes;
                    ax.YLim=[0,.1];
                end
            end
            if value==1.0
                    ax=app.UIAxes;
                    ax.YLim=[0,1.0];
            else
                if value==10
                    ax=app.UIAxes;
                    ax.YLim=[0,10];
                end
            end
            if value==100
                    ax=app.UIAxes;
                    ax.YLim=[0,100];
            else
                if value==1000
                    ax=app.UIAxes;
                    ax.YLim=[0,1000];
                end
            end
            if value==10000
                    ax=app.UIAxes;
                    ax.YLim=[0,10000];
            else
                if value==100000
                    ax=app.UIAxes;
                    ax.YLim=[0,100000];
                end
            end
            if value==1000000
                    ax=app.UIAxes;
                    ax.YLim=[0,1000000];
            end
                
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: ConsolePowerButton
        function ConsolePowerButtonValueChanged(app, event)
            value = app.ConsolePowerButton.Value;
            x = 0:0.01: (360/180)*pi;
            y = sin(x);
            plot(app.UIAxes,y);
            testfunction
        

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Reactor Power')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Reactor power (w)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Water Temperature')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Temperature (c)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Fuel Temperature')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Temperature (c)')
            zlabel(app.UIAxes3, 'Z')
            app.ReactorPowerKnob = uiknob(app.UIFigure, 'discrete');
            app.ReactorPowerKnob.Items = {'10mw', '100mw', '1W', '10W', '100W', '1KW', '10Kw', '100Kw', '1Mw'};
            app.ReactorPowerKnob.ItemsData = [0.01 0.1 1 10 100 1000 10000 100000 1000000];
            app.ReactorPowerKnob.ValueChangedFcn = createCallbackFcn(app, @ReactorPowerKnobValueChanged, true);
            app.ReactorPowerKnob.Value = 0.01;
            app.TransientUpButton = uibutton(app.UIFigure, 'push');
            app.TransientUpButton.ButtonPushedFcn = createCallbackFcn(app, @TransientUpButtonPushed, true);
            app.TransientUpButton.Text = 'Transient Up';
            app.TransientDownButton = uibutton(app.UIFigure, 'push');
            app.TransientDownButton.ButtonPushedFcn = createCallbackFcn(app, @TransientDownButtonPushed, true);
            app.TransientDownButton.Text = 'Transient Down';
            app.ReactorPowerKnobLabel = uilabel(app.UIFigure);
            app.ReactorPowerKnobLabel.Text = 'Reactor Power';
            app.SafetyUpButton = uibutton(app.UIFigure, 'push');
            app.SafetyUpButton.ButtonPushedFcn = createCallbackFcn(app, @SafetyUpButtonPushed, true);
            app.SafetyUpButton.Text = 'Safety Up';
            app.SafetyDownButton = uibutton(app.UIFigure, 'push');
            app.SafetyDownButton.ButtonPushedFcn = createCallbackFcn(app, @SafetyDownButtonPushed, true);
            app.SafetyDownButton.Text = 'Safety Down';
            app.ShimDownButton = uibutton(app.UIFigure, 'push');
            app.ShimDownButton.ButtonPushedFcn = createCallbackFcn(app, @ShimDownButtonPushed, true);
            app.ShimDownButton.Text = ' Shim Down';
            app.RegulatingDownButton = uibutton(app.UIFigure, 'push');
            app.RegulatingDownButton.ButtonPushedFcn = createCallbackFcn(app, @RegulatingDownButtonPushed, true);
            app.RegulatingDownButton.Text = 'Regulating Down';
            app.ShimUpButton = uibutton(app.UIFigure, 'push');
            app.ShimUpButton.ButtonPushedFcn = createCallbackFcn(app, @ShimUpButtonPushed, true);
            app.ShimUpButton.Text = 'Shim Up';
            app.RegulatingUpButton = uibutton(app.UIFigure, 'push');
            app.RegulatingUpButton.ButtonPushedFcn = createCallbackFcn(app, @RegulatingUpButtonPushed, true);
            app.RegulatingUpButton.Text = 'Regulating Up';
            app.ConsolePowerButton = uibutton(app.UIFigure, 'state');
            app.ConsolePowerButton.ValueChangedFcn = createCallbackFcn(app, @ConsolePowerButtonValueChanged, true);
            app.ConsolePowerButton.Text = 'Console Power';
            app.SCRAMButton = uibutton(app.UIFigure, 'state');
            app.SCRAMButton.ValueChangedFcn = createCallbackFcn(app, @SCRAMButtonValueChanged, true);
            app.SCRAMButton.Text = 'SCRAM';
            app.TransientEditFieldLabel = uilabel(app.UIFigure);
            app.TransientEditFieldLabel.Text = 'Transient';
            app.TransientEditField = uieditfield(app.UIFigure, 'numeric');
            app.SafetyEditFieldLabel = uilabel(app.UIFigure);
            app.SafetyEditFieldLabel.Text = 'Safety';
            app.SafetyEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShimEditFieldLabel = uilabel(app.UIFigure);
            app.ShimEditFieldLabel.Text = 'Shim';
            app.ShimEditField = uieditfield(app.UIFigure, 'numeric');
            app.RegulatingEditFieldLabel = uilabel(app.UIFigure);
            app.RegulatingEditFieldLabel.Text = 'Regulating';
            app.RegulatingEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reactordisplay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end